classdef tank_model_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MatthewReaneyQUBLabel           matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        OutputPlotsTab                  matlab.ui.container.Tab
        AlarmTriggeredsecondsEditField  matlab.ui.control.NumericEditField
        AlarmTriggeredsecondsEditFieldLabel  matlab.ui.control.Label
        DetectorFlagLamp                matlab.ui.control.Lamp
        DetectorFlagLampLabel           matlab.ui.control.Label
        RefreshGraphsButton             matlab.ui.control.Button
        UIAxes_Error                    matlab.ui.control.UIAxes
        UIAxes_Y_hat                    matlab.ui.control.UIAxes
        UIAxes_YAY                      matlab.ui.control.UIAxes
        UIAxes_AY                       matlab.ui.control.UIAxes
        UIAxes_Y                        matlab.ui.control.UIAxes
        UIAxes_UAU                      matlab.ui.control.UIAxes
        UIAxes_AU                       matlab.ui.control.UIAxes
        UIAxes_U                        matlab.ui.control.UIAxes
        ParameterConfigurationTab       matlab.ui.container.Tab
        SeedEditField_2Label            matlab.ui.control.Label
        SeedEditFieldLabel              matlab.ui.control.Label
        BiasAttackOffsetEditField_2Label  matlab.ui.control.Label
        Y3AttackTypeDropDownLabel       matlab.ui.control.Label
        Y2AttackTypeDropDownLabel       matlab.ui.control.Label
        FDIAttackScalerEditField_2Label  matlab.ui.control.Label
        EndTimeEditField_2Label         matlab.ui.control.Label
        StartTimeEditField_2Label       matlab.ui.control.Label
        Y1AttackTypeDropDownLabel       matlab.ui.control.Label
        U4AttackTypeDropDownLabel       matlab.ui.control.Label
        U3AttackTypeDropDownLabel       matlab.ui.control.Label
        U2AttackTypeDropDownLabel       matlab.ui.control.Label
        MaxEditField_2Label             matlab.ui.control.Label
        GeneralPerametersLabel          matlab.ui.control.Label
        StopTimeEditField               matlab.ui.control.NumericEditField
        StopTimeEditFieldLabel          matlab.ui.control.Label
        IntialStateValuesLabel          matlab.ui.control.Label
        X1EditField                     matlab.ui.control.NumericEditField
        X2EditField                     matlab.ui.control.NumericEditField
        X3EditField                     matlab.ui.control.NumericEditField
        IntialesitmatorValuesLabel      matlab.ui.control.Label
        X1EditField_2                   matlab.ui.control.NumericEditField
        X2EditField_2                   matlab.ui.control.NumericEditField
        X3EditField_2                   matlab.ui.control.NumericEditField
        MeasurementNoiseLabel           matlab.ui.control.Label
        MinEditField                    matlab.ui.control.NumericEditField
        MaxEditField                    matlab.ui.control.NumericEditField
        RandomisedSeedCheckBox          matlab.ui.control.CheckBox
        SeedEditField                   matlab.ui.control.NumericEditField
        InputNoiseLabel                 matlab.ui.control.Label
        MinEditField_2                  matlab.ui.control.NumericEditField
        MaxEditField_2                  matlab.ui.control.NumericEditField
        MinEditField_2Label             matlab.ui.control.Label
        MaxEditFieldLabel               matlab.ui.control.Label
        MinEditFieldLabel               matlab.ui.control.Label
        TotalErrorsRequirmentEditFieldLabel  matlab.ui.control.Label
        ConsecutiveErrorsRequirmentEditFieldLabel  matlab.ui.control.Label
        RandomisedSeedCheckBox_2        matlab.ui.control.CheckBox
        SeedEditField_2                 matlab.ui.control.NumericEditField
        AnomalyDetectorLabel            matlab.ui.control.Label
        ErrorThresholdEditField         matlab.ui.control.NumericEditField
        BufferSizemax100EditField       matlab.ui.control.NumericEditField
        ConsecutiveErrorsRequirmentEditField  matlab.ui.control.NumericEditField
        BufferSizemax100EditFieldLabel  matlab.ui.control.Label
        TotalErrorsRequirmentEditField  matlab.ui.control.NumericEditField
        InputUAttackLabel               matlab.ui.control.Label
        U1AttackTypeDropDown            matlab.ui.control.DropDown
        U1AttackTypeDropDownLabel       matlab.ui.control.Label
        U2AttackTypeDropDown            matlab.ui.control.DropDown
        U3AttackTypeDropDown            matlab.ui.control.DropDown
        U4AttackTypeDropDown            matlab.ui.control.DropDown
        X3EditField_2Label              matlab.ui.control.Label
        X2EditField_2Label              matlab.ui.control.Label
        X1EditField_2Label              matlab.ui.control.Label
        X3EditFieldLabel                matlab.ui.control.Label
        X2EditFieldLabel                matlab.ui.control.Label
        X1EditFieldLabel                matlab.ui.control.Label
        ErrorThresholdEditFieldLabel    matlab.ui.control.Label
        StartTimeEditField              matlab.ui.control.NumericEditField
        EndTimeEditField                matlab.ui.control.NumericEditField
        InputStealthfilterCheckBox      matlab.ui.control.CheckBox
        FDIAttackScalerEditField        matlab.ui.control.NumericEditField
        FDIAttackScalerEditFieldLabel   matlab.ui.control.Label
        EndTimeEditFieldLabel           matlab.ui.control.Label
        StartTimeEditFieldLabel         matlab.ui.control.Label
        BiasAttackOffsetEditField       matlab.ui.control.NumericEditField
        BiasAttackOffsetEditFieldLabel  matlab.ui.control.Label
        OutputYAttackLabel              matlab.ui.control.Label
        Y1AttackTypeDropDown            matlab.ui.control.DropDown
        Y2AttackTypeDropDown            matlab.ui.control.DropDown
        Y3AttackTypeDropDown            matlab.ui.control.DropDown
        StartTimeEditField_2            matlab.ui.control.NumericEditField
        EndTimeEditField_2              matlab.ui.control.NumericEditField
        OutputStealthfilterCheckBox     matlab.ui.control.CheckBox
        WIPLabel                        matlab.ui.control.Label
        FDIAttackScalerEditField_2      matlab.ui.control.NumericEditField
        BiasAttackOffsetEditField_2     matlab.ui.control.NumericEditField
        RefreshInputsButton             matlab.ui.control.Button
        ResettodefaultButton            matlab.ui.control.Button
        DPlotsTab                       matlab.ui.container.Tab
        AlarmTriggeredsecondsEditField_2  matlab.ui.control.NumericEditField
        AlarmTriggeredsecondsEditField_2Label  matlab.ui.control.Label
        DetectorFlagLamp_2              matlab.ui.control.Lamp
        DetectorFlagLamp_2Label         matlab.ui.control.Label
        RefreshGraphsButton_2           matlab.ui.control.Button
        UIAxes_Y_2                      matlab.ui.control.UIAxes
        UIAxes_Error_2                  matlab.ui.control.UIAxes
        UIAxes_Y_hat_2                  matlab.ui.control.UIAxes
        UIAxes_YAY_2                    matlab.ui.control.UIAxes
        UIAxes_AY_2                     matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            out = evalin('base','out'); %load outputs

            %% Output tab
            % plots
            plot(app.UIAxes_U,out.UN);
            plot(app.UIAxes_AU,out.AU);
            plot(app.UIAxes_UAU,out.UNA);
            plot(app.UIAxes_Y,out.YN);
            plot(app.UIAxes_AY,out.AY);
            plot(app.UIAxes_YAY,out.YNA);
            plot(app.UIAxes_Y_hat,out.Y_hat);
            plot(app.UIAxes_Error,out.error);

            % dectector flag
            app.AlarmTriggeredsecondsEditField.Value = out.flag(end);
            if out.flag(end) > 0
                app.DetectorFlagLamp.Color = [1.00,0.00,0.00];
            else
                app.DetectorFlagLamp.Color = [1.00,1.00,1.00];
            end

            %% Input Tab
            %General Parameters
            app.StopTimeEditField.Value = evalin('base','st');
            intialStates = evalin('base','intialStates');
            app.X1EditField.Value = intialStates(1);
            app.X2EditField.Value = intialStates(2);
            app.X3EditField.Value = intialStates(3);
            intialStates2 = evalin('base','intialStates2');
            app.X1EditField_2.Value = intialStates2(1);
            app.X2EditField_2.Value = intialStates2(2);
            app.X3EditField_2.Value = intialStates2(3);

            % Noise
            mn = evalin('base','mn');
            app.MinEditField.Value = mn(1);
            app.MaxEditField.Value = mn(2);
            app.SeedEditField.Value = mn(3);
            in = evalin('base','in');
            app.MinEditField_2.Value = in(1);
            app.MaxEditField_2.Value = in(2);
            app.SeedEditField_2.Value = in(3);

            % Detector
            D_Info = evalin('base','D_Info');
            app.ErrorThresholdEditField.Value = D_Info(1);
            app.BufferSizemax100EditField.Value = D_Info(2);
            app.ConsecutiveErrorsRequirmentEditField.Value = D_Info(3);
            app.TotalErrorsRequirmentEditField.Value = D_Info(4);

            % input attack
            AU_Info = evalin('base','AU_Info');
            app.U1AttackTypeDropDown.Value = AU_Info(1);
            app.U2AttackTypeDropDown.Value = AU_Info(2);
            app.U3AttackTypeDropDown.Value = AU_Info(3);
            app.U4AttackTypeDropDown.Value = AU_Info(4);
            app.StartTimeEditField.Value = AU_Info(5);
            app.EndTimeEditField.Value = AU_Info(6);
            app.InputStealthfilterCheckBox.Value = AU_Info(7);
            app.FDIAttackScalerEditField.Value = AU_Info(8);
            app.BiasAttackOffsetEditField.Value = AU_Info(9);

            % output attack
            AY_Info = evalin('base','AY_Info');
            app.Y1AttackTypeDropDown.Value = AY_Info(1);
            app.Y2AttackTypeDropDown.Value = AY_Info(2);
            app.Y3AttackTypeDropDown.Value = AY_Info(3);
            app.StartTimeEditField_2.Value = AY_Info(5);
            app.EndTimeEditField_2.Value = AY_Info(6);
            app.OutputStealthfilterCheckBox.Value = AY_Info(7);
            app.FDIAttackScalerEditField_2.Value = AY_Info(8);
            app.BiasAttackOffsetEditField_2.Value = AY_Info(9);

            %% 3D tab         
            plot3(app.UIAxes_Error_2,out.difference(:,1),out.difference(:,2),out.difference(:,3));
            plot3(app.UIAxes_Y_hat_2,out.Y_hat(:,1),out.Y_hat(:,2),out.Y_hat(:,3));
            plot3(app.UIAxes_YAY_2,out.YNA(:,1),out.YNA(:,2),out.YNA(:,3));
            plot3(app.UIAxes_AY_2,out.AY(:,1),out.AY(:,2),out.AY(:,3));
            plot3(app.UIAxes_Y_2,out.YN(:,1),out.YN(:,2),out.YN(:,3));

            % dectector flag
            app.AlarmTriggeredsecondsEditField_2.Value = out.flag(end);
            if out.flag(end) > 0
                app.DetectorFlagLamp_2.Color = [1.00,0.00,0.00];
            else
                app.DetectorFlagLamp_2.Color = [1.00,1.00,1.00];
            end
        end

        % Button pushed function: RefreshGraphsButton
        function RefreshGraphsButtonPushed(app, event)
          out = evalin('base','out'); %load outputs

            %% Output tab
            % plots
            plot(app.UIAxes_U,out.UN);
            plot(app.UIAxes_AU,out.AU);
            plot(app.UIAxes_UAU,out.UNA);
            plot(app.UIAxes_Y,out.YN);
            plot(app.UIAxes_AY,out.AY);
            plot(app.UIAxes_YAY,out.YNA);
            plot(app.UIAxes_Y_hat,out.Y_hat);
            plot(app.UIAxes_Error,out.error);

            % dectector flag
            app.AlarmTriggeredsecondsEditField.Value = out.flag(end);
            if out.flag(end) > 0
                app.DetectorFlagLamp.Color = [1.00,0.00,0.00];
            else
                app.DetectorFlagLamp.Color = [1.00,1.00,1.00];
            end

            %% Input Tab
            %General Parameters
            app.StopTimeEditField.Value = evalin('base','st');
            intialStates = evalin('base','intialStates');
            app.X1EditField.Value = intialStates(1);
            app.X2EditField.Value = intialStates(2);
            app.X3EditField.Value = intialStates(3);
            intialStates2 = evalin('base','intialStates2');
            app.X1EditField_2.Value = intialStates2(1);
            app.X2EditField_2.Value = intialStates2(2);
            app.X3EditField_2.Value = intialStates2(3);

            % Noise
            mn = evalin('base','mn');
            app.MinEditField.Value = mn(1);
            app.MaxEditField.Value = mn(2);
            app.SeedEditField.Value = mn(3);
            in = evalin('base','in');
            app.MinEditField_2.Value = in(1);
            app.MaxEditField_2.Value = in(2);
            app.SeedEditField_2.Value = in(3);

            % Detector
            D_Info = evalin('base','D_Info');
            app.ErrorThresholdEditField.Value = D_Info(1);
            app.BufferSizemax100EditField.Value = D_Info(2);
            app.ConsecutiveErrorsRequirmentEditField.Value = D_Info(3);
            app.TotalErrorsRequirmentEditField.Value = D_Info(4);

            % input attack
            AU_Info = evalin('base','AU_Info');
            app.U1AttackTypeDropDown.Value = AU_Info(1);
            app.U2AttackTypeDropDown.Value = AU_Info(2);
            app.U3AttackTypeDropDown.Value = AU_Info(3);
            app.U4AttackTypeDropDown.Value = AU_Info(4);
            app.StartTimeEditField.Value = AU_Info(5);
            app.EndTimeEditField.Value = AU_Info(6);
            app.InputStealthfilterCheckBox.Value = AU_Info(7);
            app.FDIAttackScalerEditField.Value = AU_Info(8);
            app.BiasAttackOffsetEditField.Value = AU_Info(9);

            % output attack
            AY_Info = evalin('base','AY_Info');
            app.Y1AttackTypeDropDown.Value = AY_Info(1);
            app.Y2AttackTypeDropDown.Value = AY_Info(2);
            app.Y3AttackTypeDropDown.Value = AY_Info(3);
            app.StartTimeEditField_2.Value = AY_Info(5);
            app.EndTimeEditField_2.Value = AY_Info(6);
            app.OutputStealthfilterCheckBox.Value = AY_Info(7);
            app.FDIAttackScalerEditField_2.Value = AY_Info(8);
            app.BiasAttackOffsetEditField_2.Value = AY_Info(9);

            %% 3D tab         
            plot3(app.UIAxes_Error_2,out.difference(:,1),out.difference(:,2),out.difference(:,3));
            plot3(app.UIAxes_Y_hat_2,out.Y_hat(:,1),out.Y_hat(:,2),out.Y_hat(:,3));
            plot3(app.UIAxes_YAY_2,out.YNA(:,1),out.YNA(:,2),out.YNA(:,3));
            plot3(app.UIAxes_AY_2,out.AY(:,1),out.AY(:,2),out.AY(:,3));
            plot3(app.UIAxes_Y_2,out.YN(:,1),out.YN(:,2),out.YN(:,3));

            % dectector flag
            app.AlarmTriggeredsecondsEditField_2.Value = out.flag(end);
            if out.flag(end) > 0
                app.DetectorFlagLamp_2.Color = [1.00,0.00,0.00];
            else
                app.DetectorFlagLamp_2.Color = [1.00,1.00,1.00];
            end
        end

        % Value changed function: StopTimeEditField
        function StopTimeEditFieldValueChanged(app, event)
            assignin('base','st',app.StopTimeEditField.Value);            
        end

        % Value changed function: X1EditField
        function X1EditFieldValueChanged(app, event)
            intialStates = evalin('base','intialStates'); 
            intialStates(1) = app.X1EditField.Value;
            assignin('base','intialStates',intialStates);
        end

        % Value changed function: X2EditField
        function X2EditFieldValueChanged(app, event)
            intialStates = evalin('base','intialStates'); 
            intialStates(2) = app.X2EditField.Value;
            assignin('base','intialStates',intialStates);            
        end

        % Value changed function: X3EditField
        function X3EditFieldValueChanged(app, event)
            intialStates = evalin('base','intialStates'); 
            intialStates(3) = app.X3EditField.Value;
            assignin('base','intialStates',intialStates);
        end

        % Value changed function: X1EditField_2
        function X1EditField_2ValueChanged(app, event)
            intialStates2 = evalin('base','intialStates2'); 
            intialStates2(1) = app.X1EditField_2.Value;
            assignin('base','intialStates2',intialStates2);            
        end

        % Value changed function: X2EditField_2
        function X2EditField_2ValueChanged(app, event)
            intialStates2 = evalin('base','intialStates2'); 
            intialStates2(2) = app.X2EditField_2.Value;
            assignin('base','intialStates2',intialStates2);            
        end

        % Value changed function: X3EditField_2
        function X3EditField_2ValueChanged(app, event)
            intialStates2 = evalin('base','intialStates2'); 
            intialStates2(3) = app.X3EditField_2.Value;
            assignin('base','intialStates2',intialStates2);             
        end

        % Value changed function: MinEditField
        function MinEditFieldValueChanged(app, event)
            mn = evalin('base','mn'); 
            mn(1) = app.MinEditField.Value;
            assignin('base','mn',mn); 
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
            mn = evalin('base','mn'); 
            mn(2) = app.MaxEditField.Value;
            assignin('base','mn',mn);            
        end

        % Value changed function: MinEditField_2
        function MinEditField_2ValueChanged(app, event)
            in = evalin('base','in'); 
            in(1) = app.MinEditField_2.Value;
            assignin('base','in',in);            
        end

        % Value changed function: MaxEditField_2
        function MaxEditField_2ValueChanged(app, event)
            in = evalin('base','in'); 
            in(2) = app.MaxEditField_2.Value;
            assignin('base','in',in);             
        end

        % Value changed function: ErrorThresholdEditField
        function ErrorThresholdEditFieldValueChanged(app, event)
            D_Info = evalin('base','D_Info'); 
            D_Info(1) = app.ErrorThresholdEditField.Value;
            assignin('base','D_Info',D_Info);
        end

        % Value changed function: BufferSizemax100EditField
        function BufferSizemax100EditFieldValueChanged(app, event)
            D_Info = evalin('base','D_Info'); 
            D_Info(2) = app.BufferSizemax100EditField.Value;
            assignin('base','D_Info',D_Info);
        end

        % Value changed function: ConsecutiveErrorsRequirmentEditField
        function ConsecutiveErrorsRequirmentEditFieldValueChanged(app, event)
            D_Info = evalin('base','D_Info'); 
            D_Info(3) = app.ConsecutiveErrorsRequirmentEditField.Value;
            assignin('base','D_Info',D_Info);
        end

        % Value changed function: TotalErrorsRequirmentEditField
        function TotalErrorsRequirmentEditFieldValueChanged(app, event)
            D_Info = evalin('base','D_Info'); 
            D_Info(4) = app.TotalErrorsRequirmentEditField.Value;
            assignin('base','D_Info',D_Info);
        end

        % Value changed function: U1AttackTypeDropDown
        function U1AttackTypeDropDownValueChanged(app, event)
            AU_Info = evalin('base','AU_Info'); 
            AU_Info(1) = app.U1AttackTypeDropDown.Value;
            assignin('base','AU_Info',AU_Info);            
        end

        % Value changed function: U2AttackTypeDropDown
        function U2AttackTypeDropDownValueChanged(app, event)
            AU_Info = evalin('base','AU_Info'); 
            AU_Info(2) = app.U2AttackTypeDropDown.Value;
            assignin('base','AU_Info',AU_Info);
        end

        % Value changed function: U3AttackTypeDropDown
        function U3AttackTypeDropDownValueChanged(app, event)
            AU_Info = evalin('base','AU_Info'); 
            AU_Info(3) = app.U3AttackTypeDropDown.Value;
            assignin('base','AU_Info',AU_Info);
        end

        % Value changed function: U4AttackTypeDropDown
        function U4AttackTypeDropDownValueChanged(app, event)
            AU_Info = evalin('base','AU_Info'); 
            AU_Info(4) = app.U4AttackTypeDropDown.Value;
            assignin('base','AU_Info',AU_Info);
        end

        % Value changed function: StartTimeEditField
        function StartTimeEditFieldValueChanged(app, event)
            AU_Info = evalin('base','AU_Info'); 
            AU_Info(5) = app.StartTimeEditField.Value;
            assignin('base','AU_Info',AU_Info);
        end

        % Value changed function: EndTimeEditField
        function EndTimeEditFieldValueChanged(app, event)
            AU_Info = evalin('base','AU_Info'); 
            AU_Info(6) = app.EndTimeEditField.Value;
            assignin('base','AU_Info',AU_Info);
        end

        % Value changed function: InputStealthfilterCheckBox
        function InputStealthfilterCheckBoxValueChanged(app, event)
            AU_Info = evalin('base','AU_Info'); 
            AU_Info(7) = app.InputStealthfilterCheckBox.Value;
            assignin('base','AU_Info',AU_Info);
        end

        % Value changed function: FDIAttackScalerEditField
        function FDIAttackScalerEditFieldValueChanged(app, event)
            AU_Info = evalin('base','AU_Info'); 
            AU_Info(8) = app.FDIAttackScalerEditField.Value;
            assignin('base','AU_Info',AU_Info);            
        end

        % Value changed function: BiasAttackOffsetEditField
        function BiasAttackOffsetEditFieldValueChanged(app, event)
            AU_Info = evalin('base','AU_Info'); 
            AU_Info(9) = app.BiasAttackOffsetEditField.Value;
            assignin('base','AU_Info',AU_Info); 
        end

        % Value changed function: Y1AttackTypeDropDown
        function Y1AttackTypeDropDownValueChanged(app, event)
            AY_Info = evalin('base','AY_Info'); 
            AY_Info(1) = app.Y1AttackTypeDropDown.Value;
            assignin('base','AY_Info',AY_Info);             
        end

        % Value changed function: Y2AttackTypeDropDown
        function Y2AttackTypeDropDownValueChanged(app, event)
            AY_Info = evalin('base','AY_Info'); 
            AY_Info(2) = app.Y2AttackTypeDropDown.Value;
            assignin('base','AY_Info',AY_Info); 
        end

        % Value changed function: Y3AttackTypeDropDown
        function Y3AttackTypeDropDownValueChanged(app, event)
            AY_Info = evalin('base','AY_Info'); 
            AY_Info(3) = app.Y3AttackTypeDropDown.Value;
            assignin('base','AY_Info',AY_Info);
        end

        % Value changed function: StartTimeEditField_2
        function StartTimeEditField_2ValueChanged(app, event)
            AY_Info = evalin('base','AY_Info'); 
            AY_Info(5) = app.StartTimeEditField_2.Value;
            assignin('base','AY_Info',AY_Info);
        end

        % Value changed function: EndTimeEditField_2
        function EndTimeEditField_2ValueChanged(app, event)
            AY_Info = evalin('base','AY_Info'); 
            AY_Info(6) = app.EndTimeEditField_2.Value;
            assignin('base','AY_Info',AY_Info);
        end

        % Value changed function: OutputStealthfilterCheckBox
        function OutputStealthfilterCheckBoxValueChanged(app, event)
            AY_Info = evalin('base','AY_Info'); 
            AY_Info(7) = app.OutputStealthfilterCheckBox.Value;
            assignin('base','AY_Info',AY_Info);            
        end

        % Value changed function: FDIAttackScalerEditField_2
        function FDIAttackScalerEditField_2ValueChanged(app, event)
            AY_Info = evalin('base','AY_Info'); 
            AY_Info(8) = app.FDIAttackScalerEditField_2.Value;
            assignin('base','AY_Info',AY_Info);
        end

        % Value changed function: BiasAttackOffsetEditField_2
        function BiasAttackOffsetEditField_2ValueChanged(app, event)
            AY_Info = evalin('base','AY_Info'); 
            AY_Info(9) = app.BiasAttackOffsetEditField_2.Value;
            assignin('base','AY_Info',AY_Info);
        end

        % Button pushed function: ResettodefaultButton
        function ResettodefaultButtonPushed(app, event)
            %General Parameters
            app.StopTimeEditField.Value = 5;
            assignin('base','st',app.StopTimeEditField.Value); 
            app.X1EditField.Value = 10;
            app.X2EditField.Value = 15;
            app.X3EditField.Value = 20;
            intialStates(1) = app.X1EditField.Value;
            intialStates(2) = app.X2EditField.Value;
            intialStates(3) = app.X3EditField.Value;
            assignin('base','intialStates',intialStates);
            app.X1EditField_2.Value = 9.5;
            app.X2EditField_2.Value = 14.5;
            app.X3EditField_2.Value = 19.5;
            intialStates2(1) = app.X1EditField_2.Value;
            intialStates2(2) = app.X2EditField_2.Value;
            intialStates2(3) = app.X3EditField_2.Value;
            assignin('base','intialStates2',intialStates2);

            % Noise
            app.MinEditField.Value = -0.15;
            app.MaxEditField.Value = 0.15;
            mn(1) = app.MinEditField.Value;
            mn(2) = app.MaxEditField.Value;
            mn(3) = randi(1000);
            app.SeedEditField.Value = mn(3);
            app.RandomisedSeedCheckBox.Value = 1;
            assignin('base','mn',mn); 
            app.MinEditField_2.Value = -0.05;
            app.MaxEditField_2.Value = 0.05;
            in(1) = app.MinEditField_2.Value;
            in(2) = app.MaxEditField_2.Value;
            in(3) = randi(1000);
            app.SeedEditField_2.Value = in(3);
            app.RandomisedSeedCheckBox_2.Value = 1;
            assignin('base','in',in); 

            % Detector
            D_Info = [1;5;3;3];
            app.ErrorThresholdEditField.Value = D_Info(1);
            app.BufferSizemax100EditField.Value = D_Info(2);
            app.ConsecutiveErrorsRequirmentEditField.Value = D_Info(3);
            app.TotalErrorsRequirmentEditField.Value = D_Info(4);
            assignin('base','D_Info',D_Info);

            % input attack
            AU_Info = zeros(9,1);
            app.U1AttackTypeDropDown.Value = AU_Info(1);
            app.U2AttackTypeDropDown.Value = AU_Info(2);
            app.U3AttackTypeDropDown.Value = AU_Info(3);
            app.U4AttackTypeDropDown.Value = AU_Info(4);
            app.StartTimeEditField.Value = AU_Info(5);
            app.EndTimeEditField.Value = AU_Info(6);
            app.InputStealthfilterCheckBox.Value = AU_Info(7);
            app.FDIAttackScalerEditField.Value = AU_Info(8);
            app.BiasAttackOffsetEditField.Value = AU_Info(9);
            assignin('base','AU_Info',AU_Info);

            % output attack
            AY_Info = zeros(9,1);
            app.Y1AttackTypeDropDown.Value = AY_Info(1);
            app.Y2AttackTypeDropDown.Value = AY_Info(2);
            app.Y3AttackTypeDropDown.Value = AY_Info(3);
            app.StartTimeEditField_2.Value = AY_Info(5);
            app.EndTimeEditField_2.Value = AY_Info(6);
            app.OutputStealthfilterCheckBox.Value = AY_Info(7);
            app.FDIAttackScalerEditField_2.Value = AY_Info(8);
            app.BiasAttackOffsetEditField_2.Value = AY_Info(9);
            assignin('base','AY_Info',AY_Info);
        end

        % Button pushed function: RefreshInputsButton
        function RefreshInputsButtonPushed(app, event)
            out = evalin('base','out'); %load outputs

            %% Output tab
            % plots
            plot(app.UIAxes_U,out.UN);
            plot(app.UIAxes_AU,out.AU);
            plot(app.UIAxes_UAU,out.UNA);
            plot(app.UIAxes_Y,out.YN);
            plot(app.UIAxes_AY,out.AY);
            plot(app.UIAxes_YAY,out.YNA);
            plot(app.UIAxes_Y_hat,out.Y_hat);
            plot(app.UIAxes_Error,out.error);

            % dectector flag
            app.AlarmTriggeredsecondsEditField.Value = out.flag(end);
            if out.flag(end) > 0
                app.DetectorFlagLamp.Color = [1.00,0.00,0.00];
            else
                app.DetectorFlagLamp.Color = [1.00,1.00,1.00];
            end

            %% Input Tab
            %General Parameters
            app.StopTimeEditField.Value = evalin('base','st');
            intialStates = evalin('base','intialStates');
            app.X1EditField.Value = intialStates(1);
            app.X2EditField.Value = intialStates(2);
            app.X3EditField.Value = intialStates(3);
            intialStates2 = evalin('base','intialStates2');
            app.X1EditField_2.Value = intialStates2(1);
            app.X2EditField_2.Value = intialStates2(2);
            app.X3EditField_2.Value = intialStates2(3);

            % Noise
            mn = evalin('base','mn');
            app.MinEditField.Value = mn(1);
            app.MaxEditField.Value = mn(2);
            in = evalin('base','in');
            app.MinEditField_2.Value = in(1);
            app.MaxEditField_2.Value = in(2);

            % Detector
            D_Info = evalin('base','D_Info');
            app.ErrorThresholdEditField.Value = D_Info(1);
            app.BufferSizemax100EditField.Value = D_Info(2);
            app.ConsecutiveErrorsRequirmentEditField.Value = D_Info(3);
            app.TotalErrorsRequirmentEditField.Value = D_Info(4);

            % input attack
            AU_Info = evalin('base','AU_Info');
            app.U1AttackTypeDropDown.Value = AU_Info(1);
            app.U2AttackTypeDropDown.Value = AU_Info(2);
            app.U3AttackTypeDropDown.Value = AU_Info(3);
            app.U4AttackTypeDropDown.Value = AU_Info(4);
            app.StartTimeEditField.Value = AU_Info(5);
            app.EndTimeEditField.Value = AU_Info(6);
            app.InputStealthfilterCheckBox.Value = AU_Info(7);
            app.FDIAttackScalerEditField.Value = AU_Info(8);
            app.BiasAttackOffsetEditField.Value = AU_Info(9);

            % output attack
            AY_Info = evalin('base','AY_Info');
            app.Y1AttackTypeDropDown.Value = AY_Info(1);
            app.Y2AttackTypeDropDown.Value = AY_Info(2);
            app.Y3AttackTypeDropDown.Value = AY_Info(3);
            app.StartTimeEditField_2.Value = AY_Info(5);
            app.EndTimeEditField_2.Value = AY_Info(6);
            app.OutputStealthfilterCheckBox.Value = AY_Info(7);
            app.FDIAttackScalerEditField_2.Value = AY_Info(8);
            app.BiasAttackOffsetEditField_2.Value = AY_Info(9);

            %% 3D tab         
            plot3(app.UIAxes_Error_2,out.difference(:,1),out.difference(:,2),out.difference(:,3));
            plot3(app.UIAxes_Y_hat_2,out.Y_hat(:,1),out.Y_hat(:,2),out.Y_hat(:,3));
            plot3(app.UIAxes_YAY_2,out.YNA(:,1),out.YNA(:,2),out.YNA(:,3));
            plot3(app.UIAxes_AY_2,out.AY(:,1),out.AY(:,2),out.AY(:,3));
            plot3(app.UIAxes_Y_2,out.YN(:,1),out.YN(:,2),out.YN(:,3));

            % dectector flag
            app.AlarmTriggeredsecondsEditField_2.Value = out.flag(end);
            if out.flag(end) > 0
                app.DetectorFlagLamp_2.Color = [1.00,0.00,0.00];
            else
                app.DetectorFlagLamp_2.Color = [1.00,1.00,1.00];
            end
        end

        % Button down function: OutputPlotsTab
        function OutputPlotsTabButtonDown(app, event)
           out = evalin('base','out'); %load outputs

            %% Output tab
            % plots
            plot(app.UIAxes_U,out.UN);
            plot(app.UIAxes_AU,out.AU);
            plot(app.UIAxes_UAU,out.UNA);
            plot(app.UIAxes_Y,out.YN);
            plot(app.UIAxes_AY,out.AY);
            plot(app.UIAxes_YAY,out.YNA);
            plot(app.UIAxes_Y_hat,out.Y_hat);
            plot(app.UIAxes_Error,out.error);

            % dectector flag
            app.AlarmTriggeredsecondsEditField.Value = out.flag(end);
            if out.flag(end) > 0
                app.DetectorFlagLamp.Color = [1.00,0.00,0.00];
            else
                app.DetectorFlagLamp.Color = [1.00,1.00,1.00];
            end

            %% Input Tab
            %General Parameters
            app.StopTimeEditField.Value = evalin('base','st');
            intialStates = evalin('base','intialStates');
            app.X1EditField.Value = intialStates(1);
            app.X2EditField.Value = intialStates(2);
            app.X3EditField.Value = intialStates(3);
            intialStates2 = evalin('base','intialStates2');
            app.X1EditField_2.Value = intialStates2(1);
            app.X2EditField_2.Value = intialStates2(2);
            app.X3EditField_2.Value = intialStates2(3);

            % Noise
            mn = evalin('base','mn');
            app.MinEditField.Value = mn(1);
            app.MaxEditField.Value = mn(2);
            app.SeedEditField.Value = mn(3);
            in = evalin('base','in');
            app.MinEditField_2.Value = in(1);
            app.MaxEditField_2.Value = in(2);
            app.SeedEditField_2.Value = in(3);

            % Detector
            D_Info = evalin('base','D_Info');
            app.ErrorThresholdEditField.Value = D_Info(1);
            app.BufferSizemax100EditField.Value = D_Info(2);
            app.ConsecutiveErrorsRequirmentEditField.Value = D_Info(3);
            app.TotalErrorsRequirmentEditField.Value = D_Info(4);

            % input attack
            AU_Info = evalin('base','AU_Info');
            app.U1AttackTypeDropDown.Value = AU_Info(1);
            app.U2AttackTypeDropDown.Value = AU_Info(2);
            app.U3AttackTypeDropDown.Value = AU_Info(3);
            app.U4AttackTypeDropDown.Value = AU_Info(4);
            app.StartTimeEditField.Value = AU_Info(5);
            app.EndTimeEditField.Value = AU_Info(6);
            app.InputStealthfilterCheckBox.Value = AU_Info(7);
            app.FDIAttackScalerEditField.Value = AU_Info(8);
            app.BiasAttackOffsetEditField.Value = AU_Info(9);

            % output attack
            AY_Info = evalin('base','AY_Info');
            app.Y1AttackTypeDropDown.Value = AY_Info(1);
            app.Y2AttackTypeDropDown.Value = AY_Info(2);
            app.Y3AttackTypeDropDown.Value = AY_Info(3);
            app.StartTimeEditField_2.Value = AY_Info(5);
            app.EndTimeEditField_2.Value = AY_Info(6);
            app.OutputStealthfilterCheckBox.Value = AY_Info(7);
            app.FDIAttackScalerEditField_2.Value = AY_Info(8);
            app.BiasAttackOffsetEditField_2.Value = AY_Info(9);

            %% 3D tab         
            plot3(app.UIAxes_Error_2,out.difference(:,1),out.difference(:,2),out.difference(:,3));
            plot3(app.UIAxes_Y_hat_2,out.Y_hat(:,1),out.Y_hat(:,2),out.Y_hat(:,3));
            plot3(app.UIAxes_YAY_2,out.YNA(:,1),out.YNA(:,2),out.YNA(:,3));
            plot3(app.UIAxes_AY_2,out.AY(:,1),out.AY(:,2),out.AY(:,3));
            plot3(app.UIAxes_Y_2,out.YN(:,1),out.YN(:,2),out.YN(:,3));

            % dectector flag
            app.AlarmTriggeredsecondsEditField_2.Value = out.flag(end);
            if out.flag(end) > 0
                app.DetectorFlagLamp_2.Color = [1.00,0.00,0.00];
            else
                app.DetectorFlagLamp_2.Color = [1.00,1.00,1.00];
            end
        end

        % Button down function: ParameterConfigurationTab
        function ParameterConfigurationTabButtonDown(app, event)
            out = evalin('base','out'); %load outputs

            %% Output tab
            % plots
            plot(app.UIAxes_U,out.UN);
            plot(app.UIAxes_AU,out.AU);
            plot(app.UIAxes_UAU,out.UNA);
            plot(app.UIAxes_Y,out.YN);
            plot(app.UIAxes_AY,out.AY);
            plot(app.UIAxes_YAY,out.YNA);
            plot(app.UIAxes_Y_hat,out.Y_hat);
            plot(app.UIAxes_Error,out.error);

            % dectector flag
            app.AlarmTriggeredsecondsEditField.Value = out.flag(end);
            if out.flag(end) > 0
                app.DetectorFlagLamp.Color = [1.00,0.00,0.00];
            else
                app.DetectorFlagLamp.Color = [1.00,1.00,1.00];
            end

            %% Input Tab
            %General Parameters
            app.StopTimeEditField.Value = evalin('base','st');
            intialStates = evalin('base','intialStates');
            app.X1EditField.Value = intialStates(1);
            app.X2EditField.Value = intialStates(2);
            app.X3EditField.Value = intialStates(3);
            intialStates2 = evalin('base','intialStates2');
            app.X1EditField_2.Value = intialStates2(1);
            app.X2EditField_2.Value = intialStates2(2);
            app.X3EditField_2.Value = intialStates2(3);

            % Noise
            mn = evalin('base','mn');
            app.MinEditField.Value = mn(1);
            app.MaxEditField.Value = mn(2);
            app.SeedEditField.Value = mn(3);
            in = evalin('base','in');
            app.MinEditField_2.Value = in(1);
            app.MaxEditField_2.Value = in(2);
            app.SeedEditField_2.Value = in(3);

            % Detector
            D_Info = evalin('base','D_Info');
            app.ErrorThresholdEditField.Value = D_Info(1);
            app.BufferSizemax100EditField.Value = D_Info(2);
            app.ConsecutiveErrorsRequirmentEditField.Value = D_Info(3);
            app.TotalErrorsRequirmentEditField.Value = D_Info(4);

            % input attack
            AU_Info = evalin('base','AU_Info');
            app.U1AttackTypeDropDown.Value = AU_Info(1);
            app.U2AttackTypeDropDown.Value = AU_Info(2);
            app.U3AttackTypeDropDown.Value = AU_Info(3);
            app.U4AttackTypeDropDown.Value = AU_Info(4);
            app.StartTimeEditField.Value = AU_Info(5);
            app.EndTimeEditField.Value = AU_Info(6);
            app.InputStealthfilterCheckBox.Value = AU_Info(7);
            app.FDIAttackScalerEditField.Value = AU_Info(8);
            app.BiasAttackOffsetEditField.Value = AU_Info(9);

            % output attack
            AY_Info = evalin('base','AY_Info');
            app.Y1AttackTypeDropDown.Value = AY_Info(1);
            app.Y2AttackTypeDropDown.Value = AY_Info(2);
            app.Y3AttackTypeDropDown.Value = AY_Info(3);
            app.StartTimeEditField_2.Value = AY_Info(5);
            app.EndTimeEditField_2.Value = AY_Info(6);
            app.OutputStealthfilterCheckBox.Value = AY_Info(7);
            app.FDIAttackScalerEditField_2.Value = AY_Info(8);
            app.BiasAttackOffsetEditField_2.Value = AY_Info(9);

            %% 3D tab         
            plot3(app.UIAxes_Error_2,out.difference(:,1),out.difference(:,2),out.difference(:,3));
            plot3(app.UIAxes_Y_hat_2,out.Y_hat(:,1),out.Y_hat(:,2),out.Y_hat(:,3));
            plot3(app.UIAxes_YAY_2,out.YNA(:,1),out.YNA(:,2),out.YNA(:,3));
            plot3(app.UIAxes_AY_2,out.AY(:,1),out.AY(:,2),out.AY(:,3));
            plot3(app.UIAxes_Y_2,out.YN(:,1),out.YN(:,2),out.YN(:,3));

            % dectector flag
            app.AlarmTriggeredsecondsEditField_2.Value = out.flag(end);
            if out.flag(end) > 0
                app.DetectorFlagLamp_2.Color = [1.00,0.00,0.00];
            else
                app.DetectorFlagLamp_2.Color = [1.00,1.00,1.00];
            end
        end

        % Button pushed function: RefreshGraphsButton_2
        function RefreshGraphsButton_2Pushed(app, event)
            out = evalin('base','out'); %load outputs

            %% Output tab
            % plots
            plot(app.UIAxes_U,out.UN);
            plot(app.UIAxes_AU,out.AU);
            plot(app.UIAxes_UAU,out.UNA);
            plot(app.UIAxes_Y,out.YN);
            plot(app.UIAxes_AY,out.AY);
            plot(app.UIAxes_YAY,out.YNA);
            plot(app.UIAxes_Y_hat,out.Y_hat);
            plot(app.UIAxes_Error,out.error);

            % dectector flag
            app.AlarmTriggeredsecondsEditField.Value = out.flag(end);
            if out.flag(end) > 0
                app.DetectorFlagLamp.Color = [1.00,0.00,0.00];
            else
                app.DetectorFlagLamp.Color = [1.00,1.00,1.00];
            end

            %% Input Tab
            %General Parameters
            app.StopTimeEditField.Value = evalin('base','st');
            intialStates = evalin('base','intialStates');
            app.X1EditField.Value = intialStates(1);
            app.X2EditField.Value = intialStates(2);
            app.X3EditField.Value = intialStates(3);
            intialStates2 = evalin('base','intialStates2');
            app.X1EditField_2.Value = intialStates2(1);
            app.X2EditField_2.Value = intialStates2(2);
            app.X3EditField_2.Value = intialStates2(3);

            % Noise
            mn = evalin('base','mn');
            app.MinEditField.Value = mn(1);
            app.MaxEditField.Value = mn(2);
            app.SeedEditField.Value = mn(3);
            in = evalin('base','in');
            app.MinEditField_2.Value = in(1);
            app.MaxEditField_2.Value = in(2);
            app.SeedEditField_2.Value = in(3);

            % Detector
            D_Info = evalin('base','D_Info');
            app.ErrorThresholdEditField.Value = D_Info(1);
            app.BufferSizemax100EditField.Value = D_Info(2);
            app.ConsecutiveErrorsRequirmentEditField.Value = D_Info(3);
            app.TotalErrorsRequirmentEditField.Value = D_Info(4);

            % input attack
            AU_Info = evalin('base','AU_Info');
            app.U1AttackTypeDropDown.Value = AU_Info(1);
            app.U2AttackTypeDropDown.Value = AU_Info(2);
            app.U3AttackTypeDropDown.Value = AU_Info(3);
            app.U4AttackTypeDropDown.Value = AU_Info(4);
            app.StartTimeEditField.Value = AU_Info(5);
            app.EndTimeEditField.Value = AU_Info(6);
            app.InputStealthfilterCheckBox.Value = AU_Info(7);
            app.FDIAttackScalerEditField.Value = AU_Info(8);
            app.BiasAttackOffsetEditField.Value = AU_Info(9);

            % output attack
            AY_Info = evalin('base','AY_Info');
            app.Y1AttackTypeDropDown.Value = AY_Info(1);
            app.Y2AttackTypeDropDown.Value = AY_Info(2);
            app.Y3AttackTypeDropDown.Value = AY_Info(3);
            app.StartTimeEditField_2.Value = AY_Info(5);
            app.EndTimeEditField_2.Value = AY_Info(6);
            app.OutputStealthfilterCheckBox.Value = AY_Info(7);
            app.FDIAttackScalerEditField_2.Value = AY_Info(8);
            app.BiasAttackOffsetEditField_2.Value = AY_Info(9);

            %% 3D tab         
            plot3(app.UIAxes_Error_2,out.difference(:,1),out.difference(:,2),out.difference(:,3));
            plot3(app.UIAxes_Y_hat_2,out.Y_hat(:,1),out.Y_hat(:,2),out.Y_hat(:,3));
            plot3(app.UIAxes_YAY_2,out.YNA(:,1),out.YNA(:,2),out.YNA(:,3));
            plot3(app.UIAxes_AY_2,out.AY(:,1),out.AY(:,2),out.AY(:,3));
            plot3(app.UIAxes_Y_2,out.YN(:,1),out.YN(:,2),out.YN(:,3));

            % dectector flag
            app.AlarmTriggeredsecondsEditField_2.Value = out.flag(end);
            if out.flag(end) > 0
                app.DetectorFlagLamp_2.Color = [1.00,0.00,0.00];
            else
                app.DetectorFlagLamp_2.Color = [1.00,1.00,1.00];
            end
        end

        % Button down function: DPlotsTab
        function DPlotsTabButtonDown(app, event)
            out = evalin('base','out'); %load outputs

            %% Output tab
            % plots
            plot(app.UIAxes_U,out.UN);
            plot(app.UIAxes_AU,out.AU);
            plot(app.UIAxes_UAU,out.UNA);
            plot(app.UIAxes_Y,out.YN);
            plot(app.UIAxes_AY,out.AY);
            plot(app.UIAxes_YAY,out.YNA);
            plot(app.UIAxes_Y_hat,out.Y_hat);
            plot(app.UIAxes_Error,out.error);

            % dectector flag
            app.AlarmTriggeredsecondsEditField.Value = out.flag(end);
            if out.flag(end) > 0
                app.DetectorFlagLamp.Color = [1.00,0.00,0.00];
            else
                app.DetectorFlagLamp.Color = [1.00,1.00,1.00];
            end

            %% Input Tab
            %General Parameters
            app.StopTimeEditField.Value = evalin('base','st');
            intialStates = evalin('base','intialStates');
            app.X1EditField.Value = intialStates(1);
            app.X2EditField.Value = intialStates(2);
            app.X3EditField.Value = intialStates(3);
            intialStates2 = evalin('base','intialStates2');
            app.X1EditField_2.Value = intialStates2(1);
            app.X2EditField_2.Value = intialStates2(2);
            app.X3EditField_2.Value = intialStates2(3);

            % Noise
            mn = evalin('base','mn');
            app.MinEditField.Value = mn(1);
            app.MaxEditField.Value = mn(2);
            app.SeedEditField.Value = mn(3);
            in = evalin('base','in');
            app.MinEditField_2.Value = in(1);
            app.MaxEditField_2.Value = in(2);
            app.SeedEditField_2.Value = in(3);

            % Detector
            D_Info = evalin('base','D_Info');
            app.ErrorThresholdEditField.Value = D_Info(1);
            app.BufferSizemax100EditField.Value = D_Info(2);
            app.ConsecutiveErrorsRequirmentEditField.Value = D_Info(3);
            app.TotalErrorsRequirmentEditField.Value = D_Info(4);

            % input attack
            AU_Info = evalin('base','AU_Info');
            app.U1AttackTypeDropDown.Value = AU_Info(1);
            app.U2AttackTypeDropDown.Value = AU_Info(2);
            app.U3AttackTypeDropDown.Value = AU_Info(3);
            app.U4AttackTypeDropDown.Value = AU_Info(4);
            app.StartTimeEditField.Value = AU_Info(5);
            app.EndTimeEditField.Value = AU_Info(6);
            app.InputStealthfilterCheckBox.Value = AU_Info(7);
            app.FDIAttackScalerEditField.Value = AU_Info(8);
            app.BiasAttackOffsetEditField.Value = AU_Info(9);

            % output attack
            AY_Info = evalin('base','AY_Info');
            app.Y1AttackTypeDropDown.Value = AY_Info(1);
            app.Y2AttackTypeDropDown.Value = AY_Info(2);
            app.Y3AttackTypeDropDown.Value = AY_Info(3);
            app.StartTimeEditField_2.Value = AY_Info(5);
            app.EndTimeEditField_2.Value = AY_Info(6);
            app.OutputStealthfilterCheckBox.Value = AY_Info(7);
            app.FDIAttackScalerEditField_2.Value = AY_Info(8);
            app.BiasAttackOffsetEditField_2.Value = AY_Info(9);

            %% 3D tab         
            plot3(app.UIAxes_Error_2,out.difference(:,1),out.difference(:,2),out.difference(:,3));
            plot3(app.UIAxes_Y_hat_2,out.Y_hat(:,1),out.Y_hat(:,2),out.Y_hat(:,3));
            plot3(app.UIAxes_YAY_2,out.YNA(:,1),out.YNA(:,2),out.YNA(:,3));
            plot3(app.UIAxes_AY_2,out.AY(:,1),out.AY(:,2),out.AY(:,3));
            plot3(app.UIAxes_Y_2,out.YN(:,1),out.YN(:,2),out.YN(:,3));

            % dectector flag
            app.AlarmTriggeredsecondsEditField_2.Value = out.flag(end);
            if out.flag(end) > 0
                app.DetectorFlagLamp_2.Color = [1.00,0.00,0.00];
            else
                app.DetectorFlagLamp_2.Color = [1.00,1.00,1.00];
            end
        end

        % Value changed function: RandomisedSeedCheckBox
        function RandomisedSeedCheckBoxValueChanged(app, event)
            mn = evalin('base','mn'); 
            value = app.RandomisedSeedCheckBox.Value;
            if value == 0
                mn(3) = app.SeedEditField.Value;
            else
                mn(3) = randi(1000);
            end
            app.SeedEditField.Value = mn(3);
            assignin('base','mn',mn);
        end

        % Value changed function: SeedEditField
        function SeedEditFieldValueChanged(app, event)
            mn = evalin('base','mn'); 
            value = app.RandomisedSeedCheckBox.Value;
            if value == 0
                mn(3) = app.SeedEditField.Value;
                assignin('base','mn',mn);
            else
                app.SeedEditField.Value = mn(3);
            end            
        end

        % Value changed function: RandomisedSeedCheckBox_2
        function RandomisedSeedCheckBox_2ValueChanged(app, event)
            in = evalin('base','in'); 
            value = app.RandomisedSeedCheckBox_2.Value;
            if value == 0
                in(3) = app.SeedEditField_2.Value;
            else
                in(3) = randi(1000);
            end
            app.SeedEditField_2.Value = in(3);
            assignin('base','in',in);
        end

        % Value changed function: SeedEditField_2
        function SeedEditField_2ValueChanged(app, event)
            in = evalin('base','in'); 
            value = app.RandomisedSeedCheckBox_2.Value;
            if value == 0
                in(3) = app.SeedEditField_2.Value;
                assignin('base','in',in);
            else
                app.SeedEditField_2.Value = mn(3);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OutputPlotsTab = uitab(app.TabGroup);
            app.OutputPlotsTab.Title = 'Output Plots';
            app.OutputPlotsTab.ButtonDownFcn = createCallbackFcn(app, @OutputPlotsTabButtonDown, true);
            app.UIAxes_U = uiaxes(app.OutputPlotsTab);
            title(app.UIAxes_U, 'Input (U)')
            xlabel(app.UIAxes_U, 'Time (ms)')
            zlabel(app.UIAxes_U, 'Z')
            app.UIAxes_AU = uiaxes(app.OutputPlotsTab);
            title(app.UIAxes_AU, 'Input Attack Signal (AU)')
            xlabel(app.UIAxes_AU, 'Time (ms)')
            zlabel(app.UIAxes_AU, 'Z')
            app.UIAxes_UAU = uiaxes(app.OutputPlotsTab);
            title(app.UIAxes_UAU, 'Attacked Input (U+AU)')
            xlabel(app.UIAxes_UAU, 'Time (ms)')
            zlabel(app.UIAxes_UAU, 'Z')
            app.UIAxes_Y = uiaxes(app.OutputPlotsTab);
            title(app.UIAxes_Y, 'Output (Y)')
            xlabel(app.UIAxes_Y, 'Time (ms)')
            zlabel(app.UIAxes_Y, 'Z')
            app.UIAxes_AY = uiaxes(app.OutputPlotsTab);
            title(app.UIAxes_AY, 'Output Attack Signal (AY)')
            xlabel(app.UIAxes_AY, 'Time (ms)')
            zlabel(app.UIAxes_AY, 'Z')
            app.UIAxes_YAY = uiaxes(app.OutputPlotsTab);
            title(app.UIAxes_YAY, 'Attacked Output (Y+AY)')
            xlabel(app.UIAxes_YAY, 'Time (ms)')
            zlabel(app.UIAxes_YAY, 'Z')
            app.UIAxes_Y_hat = uiaxes(app.OutputPlotsTab);
            title(app.UIAxes_Y_hat, 'Estimator (Y hat)')
            xlabel(app.UIAxes_Y_hat, 'Time (ms)')
            zlabel(app.UIAxes_Y_hat, 'Z')
            app.UIAxes_Error = uiaxes(app.OutputPlotsTab);
            title(app.UIAxes_Error, 'Error')
            xlabel(app.UIAxes_Error, 'Time (ms)')
            zlabel(app.UIAxes_Error, 'Z')
            app.RefreshGraphsButton = uibutton(app.OutputPlotsTab, 'push');
            app.RefreshGraphsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshGraphsButtonPushed, true);
            app.RefreshGraphsButton.Text = 'Refresh Graphs';
            app.DetectorFlagLampLabel = uilabel(app.OutputPlotsTab);
            app.DetectorFlagLampLabel.Text = 'Detector Flag';
            app.DetectorFlagLamp = uilamp(app.OutputPlotsTab);
            app.AlarmTriggeredsecondsEditFieldLabel = uilabel(app.OutputPlotsTab);
            app.AlarmTriggeredsecondsEditFieldLabel.Text = {'Alarm Triggered:'; '(seconds)'};
            app.AlarmTriggeredsecondsEditField = uieditfield(app.OutputPlotsTab, 'numeric');
            app.ParameterConfigurationTab = uitab(app.TabGroup);
            app.ParameterConfigurationTab.Title = 'Parameter Configuration';
            app.ParameterConfigurationTab.ButtonDownFcn = createCallbackFcn(app, @ParameterConfigurationTabButtonDown, true);
            app.ResettodefaultButton = uibutton(app.ParameterConfigurationTab, 'push');
            app.ResettodefaultButton.ButtonPushedFcn = createCallbackFcn(app, @ResettodefaultButtonPushed, true);
            app.ResettodefaultButton.Text = 'Reset to default';
            app.RefreshInputsButton = uibutton(app.ParameterConfigurationTab, 'push');
            app.RefreshInputsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshInputsButtonPushed, true);
            app.RefreshInputsButton.Text = 'Refresh Inputs';
            app.BiasAttackOffsetEditField_2 = uieditfield(app.ParameterConfigurationTab, 'numeric');
            app.BiasAttackOffsetEditField_2.ValueChangedFcn = createCallbackFcn(app, @BiasAttackOffsetEditField_2ValueChanged, true);
            app.FDIAttackScalerEditField_2 = uieditfield(app.ParameterConfigurationTab, 'numeric');
            app.FDIAttackScalerEditField_2.ValueChangedFcn = createCallbackFcn(app, @FDIAttackScalerEditField_2ValueChanged, true);
            app.WIPLabel = uilabel(app.ParameterConfigurationTab);
            app.WIPLabel.Text = '(WIP)';
            app.OutputStealthfilterCheckBox = uicheckbox(app.ParameterConfigurationTab);
            app.OutputStealthfilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @OutputStealthfilterCheckBoxValueChanged, true);
            app.OutputStealthfilterCheckBox.Text = 'Output Stealth filter';
            app.EndTimeEditField_2 = uieditfield(app.ParameterConfigurationTab, 'numeric');
            app.EndTimeEditField_2.ValueChangedFcn = createCallbackFcn(app, @EndTimeEditField_2ValueChanged, true);
            app.StartTimeEditField_2 = uieditfield(app.ParameterConfigurationTab, 'numeric');
            app.StartTimeEditField_2.ValueChangedFcn = createCallbackFcn(app, @StartTimeEditField_2ValueChanged, true);
            app.Y3AttackTypeDropDown = uidropdown(app.ParameterConfigurationTab);
            app.Y3AttackTypeDropDown.Items = {'None', 'FDI', 'Bias', 'Dos', 'Sign Alternation', 'Rerouting', 'Replay'};
            app.Y3AttackTypeDropDown.ItemsData = [0 1 2 3 4 5 6];
            app.Y3AttackTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @Y3AttackTypeDropDownValueChanged, true);
            app.Y3AttackTypeDropDown.Placeholder = 'None';
            app.Y3AttackTypeDropDown.Value = 0;
            app.Y2AttackTypeDropDown = uidropdown(app.ParameterConfigurationTab);
            app.Y2AttackTypeDropDown.Items = {'None', 'FDI', 'Bias', 'Dos', 'Sign Alternation', 'Rerouting', 'Replay'};
            app.Y2AttackTypeDropDown.ItemsData = [0 1 2 3 4 5 6];
            app.Y2AttackTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @Y2AttackTypeDropDownValueChanged, true);
            app.Y2AttackTypeDropDown.Placeholder = 'None';
            app.Y2AttackTypeDropDown.Value = 0;
            app.Y1AttackTypeDropDown = uidropdown(app.ParameterConfigurationTab);
            app.Y1AttackTypeDropDown.Items = {'None', 'FDI', 'Bias', 'Dos', 'Sign Alternation', 'Rerouting', 'Replay'};
            app.Y1AttackTypeDropDown.ItemsData = [0 1 2 3 4 5 6];
            app.Y1AttackTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @Y1AttackTypeDropDownValueChanged, true);
            app.Y1AttackTypeDropDown.Placeholder = 'None';
            app.Y1AttackTypeDropDown.Value = 0;
            app.OutputYAttackLabel = uilabel(app.ParameterConfigurationTab);
            app.OutputYAttackLabel.Text = 'Output (Y) Attack';
            app.BiasAttackOffsetEditFieldLabel = uilabel(app.ParameterConfigurationTab);
            app.BiasAttackOffsetEditFieldLabel.Text = 'Bias Attack Offset';
            app.BiasAttackOffsetEditField = uieditfield(app.ParameterConfigurationTab, 'numeric');
            app.BiasAttackOffsetEditField.ValueChangedFcn = createCallbackFcn(app, @BiasAttackOffsetEditFieldValueChanged, true);
            app.StartTimeEditFieldLabel = uilabel(app.ParameterConfigurationTab);
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.EndTimeEditFieldLabel = uilabel(app.ParameterConfigurationTab);
            app.EndTimeEditFieldLabel.Text = 'End Time';
            app.FDIAttackScalerEditFieldLabel = uilabel(app.ParameterConfigurationTab);
            app.FDIAttackScalerEditFieldLabel.Text = 'FDI Attack Scaler';
            app.FDIAttackScalerEditField = uieditfield(app.ParameterConfigurationTab, 'numeric');
            app.FDIAttackScalerEditField.ValueChangedFcn = createCallbackFcn(app, @FDIAttackScalerEditFieldValueChanged, true);
            app.InputStealthfilterCheckBox = uicheckbox(app.ParameterConfigurationTab);
            app.InputStealthfilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @InputStealthfilterCheckBoxValueChanged, true);
            app.InputStealthfilterCheckBox.Text = 'Input Stealth filter';
            app.EndTimeEditField = uieditfield(app.ParameterConfigurationTab, 'numeric');
            app.EndTimeEditField.ValueChangedFcn = createCallbackFcn(app, @EndTimeEditFieldValueChanged, true);
            app.StartTimeEditField = uieditfield(app.ParameterConfigurationTab, 'numeric');
            app.StartTimeEditField.ValueChangedFcn = createCallbackFcn(app, @StartTimeEditFieldValueChanged, true);
            app.ErrorThresholdEditFieldLabel = uilabel(app.ParameterConfigurationTab);
            app.ErrorThresholdEditFieldLabel.Text = 'Error Threshold';
            app.X1EditFieldLabel = uilabel(app.ParameterConfigurationTab);
            app.X1EditFieldLabel.Text = 'X1';
            app.X2EditFieldLabel = uilabel(app.ParameterConfigurationTab);
            app.X2EditFieldLabel.Text = 'X2';
            app.X3EditFieldLabel = uilabel(app.ParameterConfigurationTab);
            app.X3EditFieldLabel.Text = 'X3';
            app.X1EditField_2Label = uilabel(app.ParameterConfigurationTab);
            app.X1EditField_2Label.Text = 'X1';
            app.X2EditField_2Label = uilabel(app.ParameterConfigurationTab);
            app.X2EditField_2Label.Text = 'X2';
            app.X3EditField_2Label = uilabel(app.ParameterConfigurationTab);
            app.X3EditField_2Label.Text = 'X3';
            app.U4AttackTypeDropDown = uidropdown(app.ParameterConfigurationTab);
            app.U4AttackTypeDropDown.Items = {'None', 'FDI', 'Bias', 'Dos', 'Sign Alternation', 'Rerouting', 'Replay'};
            app.U4AttackTypeDropDown.ItemsData = [0 1 2 3 4 5 6];
            app.U4AttackTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @U4AttackTypeDropDownValueChanged, true);
            app.U4AttackTypeDropDown.Placeholder = 'None';
            app.U4AttackTypeDropDown.Value = 0;
            app.U3AttackTypeDropDown = uidropdown(app.ParameterConfigurationTab);
            app.U3AttackTypeDropDown.Items = {'None', 'FDI', 'Bias', 'Dos', 'Sign Alternation', 'Rerouting', 'Replay'};
            app.U3AttackTypeDropDown.ItemsData = [0 1 2 3 4 5 6];
            app.U3AttackTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @U3AttackTypeDropDownValueChanged, true);
            app.U3AttackTypeDropDown.Placeholder = 'None';
            app.U3AttackTypeDropDown.Value = 0;
            app.U2AttackTypeDropDown = uidropdown(app.ParameterConfigurationTab);
            app.U2AttackTypeDropDown.Items = {'None', 'FDI', 'Bias', 'Dos', 'Sign Alternation', 'Rerouting', 'Replay'};
            app.U2AttackTypeDropDown.ItemsData = [0 1 2 3 4 5 6];
            app.U2AttackTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @U2AttackTypeDropDownValueChanged, true);
            app.U2AttackTypeDropDown.Placeholder = 'None';
            app.U2AttackTypeDropDown.Value = 0;
            app.U1AttackTypeDropDownLabel = uilabel(app.ParameterConfigurationTab);
            app.U1AttackTypeDropDownLabel.Text = {'U1'; 'Attack Type'};
            app.U1AttackTypeDropDown = uidropdown(app.ParameterConfigurationTab);
            app.U1AttackTypeDropDown.Items = {'None', 'FDI', 'Bias', 'Dos', 'Sign Alternation', 'Rerouting', 'Replay'};
            app.U1AttackTypeDropDown.ItemsData = [0 1 2 3 4 5 6];
            app.U1AttackTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @U1AttackTypeDropDownValueChanged, true);
            app.U1AttackTypeDropDown.Placeholder = 'None';
            app.U1AttackTypeDropDown.Value = 0;
            app.InputUAttackLabel = uilabel(app.ParameterConfigurationTab);
            app.InputUAttackLabel.Text = 'Input (U) Attack';
            app.TotalErrorsRequirmentEditField = uieditfield(app.ParameterConfigurationTab, 'numeric');
            app.TotalErrorsRequirmentEditField.ValueChangedFcn = createCallbackFcn(app, @TotalErrorsRequirmentEditFieldValueChanged, true);
            app.BufferSizemax100EditFieldLabel = uilabel(app.ParameterConfigurationTab);
            app.BufferSizemax100EditFieldLabel.Text = {'Buffer Size'; '(max 100)'};
            app.ConsecutiveErrorsRequirmentEditField = uieditfield(app.ParameterConfigurationTab, 'numeric');
            app.ConsecutiveErrorsRequirmentEditField.ValueChangedFcn = createCallbackFcn(app, @ConsecutiveErrorsRequirmentEditFieldValueChanged, true);
            app.BufferSizemax100EditField = uieditfield(app.ParameterConfigurationTab, 'numeric');
            app.BufferSizemax100EditField.ValueChangedFcn = createCallbackFcn(app, @BufferSizemax100EditFieldValueChanged, true);
            app.ErrorThresholdEditField = uieditfield(app.ParameterConfigurationTab, 'numeric');
            app.ErrorThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ErrorThresholdEditFieldValueChanged, true);
            app.AnomalyDetectorLabel = uilabel(app.ParameterConfigurationTab);
            app.AnomalyDetectorLabel.Text = ' Anomaly Detector';
            app.SeedEditField_2 = uieditfield(app.ParameterConfigurationTab, 'numeric');
            app.SeedEditField_2.ValueChangedFcn = createCallbackFcn(app, @SeedEditField_2ValueChanged, true);
            app.RandomisedSeedCheckBox_2 = uicheckbox(app.ParameterConfigurationTab);
            app.RandomisedSeedCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @RandomisedSeedCheckBox_2ValueChanged, true);
            app.RandomisedSeedCheckBox_2.Text = 'Randomised Seed';
            app.RandomisedSeedCheckBox_2.Value = true;
            app.ConsecutiveErrorsRequirmentEditFieldLabel = uilabel(app.ParameterConfigurationTab);
            app.ConsecutiveErrorsRequirmentEditFieldLabel.Text = {'Consecutive Errors '; 'Requirment'};
            app.TotalErrorsRequirmentEditFieldLabel = uilabel(app.ParameterConfigurationTab);
            app.TotalErrorsRequirmentEditFieldLabel.Text = {'Total Errors '; 'Requirment'};
            app.MinEditFieldLabel = uilabel(app.ParameterConfigurationTab);
            app.MinEditFieldLabel.Text = 'Min';
            app.MaxEditFieldLabel = uilabel(app.ParameterConfigurationTab);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MinEditField_2Label = uilabel(app.ParameterConfigurationTab);
            app.MinEditField_2Label.Text = 'Min';
            app.MaxEditField_2 = uieditfield(app.ParameterConfigurationTab, 'numeric');
            app.MaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @MaxEditField_2ValueChanged, true);
            app.MinEditField_2 = uieditfield(app.ParameterConfigurationTab, 'numeric');
            app.MinEditField_2.ValueChangedFcn = createCallbackFcn(app, @MinEditField_2ValueChanged, true);
            app.InputNoiseLabel = uilabel(app.ParameterConfigurationTab);
            app.InputNoiseLabel.Text = 'Input Noise';
            app.SeedEditField = uieditfield(app.ParameterConfigurationTab, 'numeric');
            app.SeedEditField.ValueChangedFcn = createCallbackFcn(app, @SeedEditFieldValueChanged, true);
            app.RandomisedSeedCheckBox = uicheckbox(app.ParameterConfigurationTab);
            app.RandomisedSeedCheckBox.ValueChangedFcn = createCallbackFcn(app, @RandomisedSeedCheckBoxValueChanged, true);
            app.RandomisedSeedCheckBox.Text = 'Randomised Seed';
            app.RandomisedSeedCheckBox.Value = true;
            app.MaxEditField = uieditfield(app.ParameterConfigurationTab, 'numeric');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.MinEditField = uieditfield(app.ParameterConfigurationTab, 'numeric');
            app.MinEditField.ValueChangedFcn = createCallbackFcn(app, @MinEditFieldValueChanged, true);
            app.MeasurementNoiseLabel = uilabel(app.ParameterConfigurationTab);
            app.MeasurementNoiseLabel.Text = 'Measurement Noise';
            app.X3EditField_2 = uieditfield(app.ParameterConfigurationTab, 'numeric');
            app.X3EditField_2.ValueChangedFcn = createCallbackFcn(app, @X3EditField_2ValueChanged, true);
            app.X2EditField_2 = uieditfield(app.ParameterConfigurationTab, 'numeric');
            app.X2EditField_2.ValueChangedFcn = createCallbackFcn(app, @X2EditField_2ValueChanged, true);
            app.X1EditField_2 = uieditfield(app.ParameterConfigurationTab, 'numeric');
            app.X1EditField_2.ValueChangedFcn = createCallbackFcn(app, @X1EditField_2ValueChanged, true);
            app.IntialesitmatorValuesLabel = uilabel(app.ParameterConfigurationTab);
            app.IntialesitmatorValuesLabel.Text = 'Intial esitmator Values ';
            app.X3EditField = uieditfield(app.ParameterConfigurationTab, 'numeric');
            app.X3EditField.ValueChangedFcn = createCallbackFcn(app, @X3EditFieldValueChanged, true);
            app.X2EditField = uieditfield(app.ParameterConfigurationTab, 'numeric');
            app.X2EditField.ValueChangedFcn = createCallbackFcn(app, @X2EditFieldValueChanged, true);
            app.X1EditField = uieditfield(app.ParameterConfigurationTab, 'numeric');
            app.X1EditField.ValueChangedFcn = createCallbackFcn(app, @X1EditFieldValueChanged, true);
            app.IntialStateValuesLabel = uilabel(app.ParameterConfigurationTab);
            app.IntialStateValuesLabel.Text = 'Intial State Values ';
            app.StopTimeEditFieldLabel = uilabel(app.ParameterConfigurationTab);
            app.StopTimeEditFieldLabel.Text = 'Stop Time';
            app.StopTimeEditField = uieditfield(app.ParameterConfigurationTab, 'numeric');
            app.StopTimeEditField.ValueChangedFcn = createCallbackFcn(app, @StopTimeEditFieldValueChanged, true);
            app.GeneralPerametersLabel = uilabel(app.ParameterConfigurationTab);
            app.GeneralPerametersLabel.Text = 'General Perameters';
            app.MaxEditField_2Label = uilabel(app.ParameterConfigurationTab);
            app.MaxEditField_2Label.Text = 'Max';
            app.U2AttackTypeDropDownLabel = uilabel(app.ParameterConfigurationTab);
            app.U2AttackTypeDropDownLabel.Text = {'U2'; 'Attack Type'};
            app.U3AttackTypeDropDownLabel = uilabel(app.ParameterConfigurationTab);
            app.U3AttackTypeDropDownLabel.Text = {'U3'; 'Attack Type'};
            app.U4AttackTypeDropDownLabel = uilabel(app.ParameterConfigurationTab);
            app.U4AttackTypeDropDownLabel.Text = {'U4'; 'Attack Type'};
            app.Y1AttackTypeDropDownLabel = uilabel(app.ParameterConfigurationTab);
            app.Y1AttackTypeDropDownLabel.Text = {'Y1'; 'Attack Type'};
            app.StartTimeEditField_2Label = uilabel(app.ParameterConfigurationTab);
            app.StartTimeEditField_2Label.Text = 'Start Time';
            app.EndTimeEditField_2Label = uilabel(app.ParameterConfigurationTab);
            app.EndTimeEditField_2Label.Text = 'End Time';
            app.FDIAttackScalerEditField_2Label = uilabel(app.ParameterConfigurationTab);
            app.FDIAttackScalerEditField_2Label.Text = 'FDI Attack Scaler';
            app.Y2AttackTypeDropDownLabel = uilabel(app.ParameterConfigurationTab);
            app.Y2AttackTypeDropDownLabel.Text = {'Y2'; 'Attack Type'};
            app.Y3AttackTypeDropDownLabel = uilabel(app.ParameterConfigurationTab);
            app.Y3AttackTypeDropDownLabel.Text = {'Y3'; 'Attack Type'};
            app.BiasAttackOffsetEditField_2Label = uilabel(app.ParameterConfigurationTab);
            app.BiasAttackOffsetEditField_2Label.Text = 'Bias Attack Offset';
            app.SeedEditFieldLabel = uilabel(app.ParameterConfigurationTab);
            app.SeedEditFieldLabel.Text = 'Seed';
            app.SeedEditField_2Label = uilabel(app.ParameterConfigurationTab);
            app.SeedEditField_2Label.Text = 'Seed';
            app.DPlotsTab = uitab(app.TabGroup);
            app.DPlotsTab.Title = '3D Plots';
            app.DPlotsTab.ButtonDownFcn = createCallbackFcn(app, @DPlotsTabButtonDown, true);
            app.UIAxes_AY_2 = uiaxes(app.DPlotsTab);
            title(app.UIAxes_AY_2, 'Output Attack Signal (AY) 3D')
            app.UIAxes_YAY_2 = uiaxes(app.DPlotsTab);
            title(app.UIAxes_YAY_2, 'Attacked Output (Y+AY) 3D')
            app.UIAxes_Y_hat_2 = uiaxes(app.DPlotsTab);
            title(app.UIAxes_Y_hat_2, 'Estimator (Y hat) 3D')
            app.UIAxes_Error_2 = uiaxes(app.DPlotsTab);
            title(app.UIAxes_Error_2, 'Error 3D')
            app.UIAxes_Y_2 = uiaxes(app.DPlotsTab);
            title(app.UIAxes_Y_2, 'Output (Y) 3D')
            app.RefreshGraphsButton_2 = uibutton(app.DPlotsTab, 'push');
            app.RefreshGraphsButton_2.ButtonPushedFcn = createCallbackFcn(app, @RefreshGraphsButton_2Pushed, true);
            app.RefreshGraphsButton_2.Text = 'Refresh Graphs';
            app.DetectorFlagLamp_2Label = uilabel(app.DPlotsTab);
            app.DetectorFlagLamp_2Label.Text = 'Detector Flag';
            app.DetectorFlagLamp_2 = uilamp(app.DPlotsTab);
            app.AlarmTriggeredsecondsEditField_2Label = uilabel(app.DPlotsTab);
            app.AlarmTriggeredsecondsEditField_2Label.Text = {'Alarm Triggered:'; '(seconds)'};
            app.AlarmTriggeredsecondsEditField_2 = uieditfield(app.DPlotsTab, 'numeric');
            app.MatthewReaneyQUBLabel = uilabel(app.UIFigure);
            app.MatthewReaneyQUBLabel.Text = 'Matthew Reaney QUB.';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tank_model_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end